classdef eToolsFilterDesignApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimTab                          matlab.ui.container.Tab
        ComponentValuePanel             matlab.ui.container.Panel
        LDropDown                       matlab.ui.control.DropDown
        LDropDownLabel                  matlab.ui.control.Label
        CDropDown                       matlab.ui.control.DropDown
        CDropDownLabel                  matlab.ui.control.Label
        ExactCheckBox                   matlab.ui.control.CheckBox
        ENCheckBox_TeeEn                matlab.ui.control.CheckBox
        SimulationButton                matlab.ui.control.Button
        TranPanel                       matlab.ui.container.Panel
        HzLabel_TFstop                  matlab.ui.control.Label
        HzLabel_TFstart                 matlab.ui.control.Label
        ENCheckBox_TranEn               matlab.ui.control.CheckBox
        EditField_fs                    matlab.ui.control.EditField
        fsLabel                         matlab.ui.control.Label
        EditField_tstop                 matlab.ui.control.EditField
        tstopLabel                      matlab.ui.control.Label
        ACPanel                         matlab.ui.container.Panel
        ENCheckBox_PZ                   matlab.ui.control.CheckBox
        HzLabel_ACFstop                 matlab.ui.control.Label
        HzLabel_ACFstart                matlab.ui.control.Label
        ENCheckBox_ACEn                 matlab.ui.control.CheckBox
        EditField_fstop                 matlab.ui.control.EditField
        fstopLabel                      matlab.ui.control.Label
        EditField_fstart                matlab.ui.control.EditField
        fstartLabel                     matlab.ui.control.Label
        SynthesisButton                 matlab.ui.control.Button
        FilterParametersPanel           matlab.ui.container.Panel
        EditField_Apass                 matlab.ui.control.EditField
        ApLabel_2                       matlab.ui.control.Label
        dBLabel_Ap                      matlab.ui.control.Label
        ENCheckBox_AstopEn              matlab.ui.control.CheckBox
        Label_Rl                        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        dBLabel_Asr                     matlab.ui.control.Label
        HzLabel_Fs                      matlab.ui.control.Label
        dBLabel_Apr                     matlab.ui.control.Label
        HzLabel_BW                      matlab.ui.control.Label
        HzLabel_Fp                      matlab.ui.control.Label
        EditField_BW                    matlab.ui.control.EditField
        BWLabel                         matlab.ui.control.Label
        EditField_Zout                  matlab.ui.control.EditField
        RlLabel                         matlab.ui.control.Label
        EditField_Zin                   matlab.ui.control.EditField
        RsLabel                         matlab.ui.control.Label
        EditField_Asr                   matlab.ui.control.EditField
        AsrLabel                        matlab.ui.control.Label
        EditField_Apr                   matlab.ui.control.EditField
        AprLabel                        matlab.ui.control.Label
        EditField_F2                    matlab.ui.control.EditField
        FsLabel                         matlab.ui.control.Label
        EditField_F1                    matlab.ui.control.EditField
        FpLabel                         matlab.ui.control.Label
        ButterworthDropDownOrder        matlab.ui.control.DropDown
        OrderLabel                      matlab.ui.control.Label
        ButterworthDropDownFilterType2  matlab.ui.control.DropDown
        TypeLabel                       matlab.ui.control.Label
        ButterworthDropDownFilterType1  matlab.ui.control.DropDown
        ShapeLabel                      matlab.ui.control.Label
        TabGroupPlot                    matlab.ui.container.TabGroup
        TabMag                          matlab.ui.container.Tab
        logscaleCheckBox                matlab.ui.control.CheckBox
        UIAxesMag                       matlab.ui.control.UIAxes
        TabPhase                        matlab.ui.container.Tab
        UIAxesPhase                     matlab.ui.control.UIAxes
        PZTab                           matlab.ui.container.Tab
        UIAxesPZ3D                      matlab.ui.control.UIAxes
        UIAxesPZ                        matlab.ui.control.UIAxes
        TabTran                         matlab.ui.container.Tab
        UIAxesTran                      matlab.ui.control.UIAxes
        UIAxesSch                       matlab.ui.control.UIAxes
        GuideTab                        matlab.ui.container.Tab
        OptTab                          matlab.ui.container.Tab
        ExportTab                       matlab.ui.container.Tab
        SettingTab                      matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'eToolsFilterDesignApp';
            set(app.EditField_Asr, 'Enable', 'off');
            set(app.EditField_Apr, 'Enable', 'off');
            set(app.ENCheckBox_ACEn, 'Value', 1);
            set(app.ENCheckBox_TranEn, 'Value', 1);
            set(app.ExactCheckBox, 'Value', 1);
            set(app.logscaleCheckBox, 'Value', 1);
            ENCheckBox_AstopEnValueChanged(app, []);
            ExactCheckBoxValueChanged(app, []);
            ButterworthDropDownFilterType1ValueChanged(app, []);
            SynthesisButtonPushed(app, []);
        end

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
            fShape = app.ButterworthDropDownFilterType1.Value;
            n      = str2double(app.ButterworthDropDownOrder.Value);
            Rs     = Suffix2Data(app.EditField_Zin.Value);
            Rl     = Suffix2Data(app.EditField_Zout.Value);
            fp     = Suffix2Data(app.EditField_F1.Value);
            fs     = Suffix2Data(app.EditField_F2.Value);
            Apr    = Suffix2Data(app.EditField_Apr.Value);
            Ap     = Suffix2Data(app.EditField_Apass.Value);
            Asr    = Suffix2Data(app.EditField_Asr.Value);
            TeeEn  = (app.ENCheckBox_TeeEn.Value);
            bw     = Suffix2Data(app.EditField_BW.Value);
            fType  = app.ButterworthDropDownFilterType2.Value;
            logscaleEn = app.logscaleCheckBox.Value;
            global strNetlist;
            if ~exist('strNetlist','var') || isempty(strNetlist)
                SynthesisButtonPushed(app, event);
            end
            % split netlist
            [iType, Value, cellNode1, CellNode2, cellName] = funSimNetlist2Array(strNetlist);
            % netlist standard
            [node1, node2] = funSimNetlistRenode(cellNode1, CellNode2);
            % netlist analysis
            [maxNode, nL, nI, nV, nR0] = funSimNetlistAna(iType, Value, node1, node2);
            % 标记给定器件的非GND节点用于获取结果位置
            strDevice = 'RL'; % 显示结果的器件
            [retNode] = funGetDeviceNode(cellName, node1, node2, strDevice);
            % generate matrix, (MM*s+MN)*MX = MV
            [MM, MN, MV, MX] = funSimNetlist2Matrix(iType, Value, node1, node2, maxNode, nL, nI, nV, nR0, cellName);
            
            %% AC sim
            f0 = Suffix2Data(app.EditField_fstart.Value);
            f1 = Suffix2Data(app.EditField_fstop.Value);
            N       = 500;
            [IdealFreq, IdealMag, IdealPhase, P, Z, f_min] = funSimFilterIdeal(fType, TeeEn, n, Rs, Rl, fp, fs, Ap, Apr, Asr, bw, fShape, f0, f1, N);
            if get(app.ENCheckBox_ACEn, 'Value')
                axMag   = app.UIAxesMag;
                axPhase = app.UIAxesPhase;
                funACSim(axMag, axPhase, f0, f1, N, cellName, MX, MM, MN, MV, Value, node1, node2, IdealFreq, IdealMag, IdealPhase, logscaleEn);
                if strcmp(fType, 'LinearAmp')
                    ylim(axMag, [-10, 0]);
                end
            end
            %% PZ sim
            if get(app.ENCheckBox_PZ, 'Value')
                switch fShape
                    case {'LPF', 'HPF'}
                        N      = 12*n;
                        if N<50
                            N  = 50;
                        end
                        if N>100
                            N  = 100;
                        end
                        ff = max(abs(P))*1.5;
                    case {'BPF', 'BRF'}
                        N      = 20*n;
                        if N<80
                            N  = 80;
                        end
                        if N>160*1
                            N  = 160*1;
                        end
                        ff = max(abs(P))*1.5;
                end
                if Rl == 0 || Rs == 0 || Rl == inf || Rs == inf
                    A0     = 0;
                else
                    A0     = 10*log10(1/4*sqrt(Rs/Rl));
                end
                axPZ   = app.UIAxesPZ;
                axPZ3D = app.UIAxesPZ3D;
                funPZSim(axPZ, axPZ3D, [], ff, N, cellName, MX, MM, MN, MV, Value, node1, node2, A0, Ap, P, Z);
            end
            %% Tran Sim
            if get(app.ENCheckBox_TranEn, 'Value')
                Tmax   = Suffix2Data(app.EditField_tstop.Value);
                f1     = Suffix2Data(app.EditField_fs.Value);
                Nmax   = 100000; % 
                axTran = app.UIAxesTran;
                funTranSim(axTran, f1, Tmax, Nmax, MX, MM, MN, MV, retNode, cellName, Value);
            end
        end

        % Value changed function: ENCheckBox_AstopEn
        function ENCheckBox_AstopEnValueChanged(app, event)
            value = app.ENCheckBox_AstopEn.Value;
            fType = app.ButterworthDropDownFilterType2.Value;
            if value
                set(app.EditField_Asr, 'Enable', 'on');
                set(app.EditField_Apr, 'Enable', 'on');
                set(app.EditField_F2, 'Enable', 'on');
                set(app.ButterworthDropDownOrder, 'Enable', 'off');
            else
                switch fType
                    case 'Chebyshev I'
                        if isempty(app.EditField_Apr.Value)
                            set(app.EditField_Apr, 'Value', '0.044');
                        end
                        set(app.EditField_Apr, 'Enable', 'on');
                        set(app.EditField_Asr, 'Enable', 'off');
                    case 'Chebyshev II'
                        if isempty(app.EditField_Asr.Value)
                            set(app.EditField_Asr, 'Value', '60');
                        end
                        set(app.EditField_Asr, 'Enable', 'on');
                        set(app.EditField_Apr, 'Enable', 'off');
                    case 'Elliptic'
                        if isempty(app.EditField_Asr.Value)
                            set(app.EditField_Asr, 'Value', '60');
                        end
                        if isempty(app.EditField_Apr.Value)
                            set(app.EditField_Apr, 'Value', '0.044');
                        end
                        set(app.EditField_Asr, 'Enable', 'on');
                        set(app.EditField_Apr, 'Enable', 'on');
                    otherwise
                        set(app.EditField_Asr, 'Enable', 'off');
                        set(app.EditField_Apr, 'Enable', 'off');
                end
                
                set(app.EditField_F2, 'Enable', 'off');
                set(app.ButterworthDropDownOrder, 'Enable', 'on');
            end
        end

        % Value changed function: ENCheckBox_TeeEn
        function ENCheckBox_TeeEnValueChanged(app, event)
            value = app.ENCheckBox_TeeEn.Value;
            SynthesisButtonPushed(app, event);
        end

        % Button pushed function: SynthesisButton
        function SynthesisButtonPushed(app, event)
            global strNetlist;
            fShape = app.ButterworthDropDownFilterType1.Value;
            n      = str2double(app.ButterworthDropDownOrder.Value);
            Rs     = Suffix2Data(app.EditField_Zin.Value);
            Rl     = Suffix2Data(app.EditField_Zout.Value);
            fp     = Suffix2Data(app.EditField_F1.Value);
            fs     = Suffix2Data(app.EditField_F2.Value);
            Apr    = Suffix2Data(app.EditField_Apr.Value);
            Ap     = Suffix2Data(app.EditField_Apass.Value);
            Asr     = Suffix2Data(app.EditField_Asr.Value);
            TeeEn  = (app.ENCheckBox_TeeEn.Value);
            bw     = Suffix2Data(app.EditField_BW.Value);
            fType  = app.ButterworthDropDownFilterType2.Value;
            ExactValue = app.ExactCheckBox.Value;
            [strNetlist] = funSynthesisFilter(fType, TeeEn, n, Rs, Rl, fp, fs, Ap, Apr, Asr, bw, fShape);
            [iType, Value, cellNode1, CellNode2, cellName] = funSimNetlist2Array(strNetlist);
            % 实际器件替换
            if ~ExactValue
                strNL = app.LDropDown.Value;
                strNC = app.CDropDown.Value;
                [iType, Value] = funSynthesisFilterActual(iType, Value, strNL, strNC);
                [strNetlist] = funSimArray2Netlist(iType, Value, cellNode1, CellNode2, cellName);
            end
            f0     = fp;
            w0     = 2*pi*f0;
            axPlot = app.UIAxesSch;
            [img] = funGenSchematic2(axPlot, iType, Value, cellNode1, CellNode2, cellName, w0);
            ylimValue = ylim(axPlot);
            ylim(axPlot, [-ceil(-ylimValue(1)), ceil(ylimValue(2))]);
        end

        % Value changed function: ButterworthDropDownFilterType1
        function ButterworthDropDownFilterType1ValueChanged(app, event)
            fType = app.ButterworthDropDownFilterType1.Value;
            switch fType
                case {'BPF', 'BRF'}
                    set(app.EditField_BW, 'Enable', 'on');
                otherwise
                    set(app.EditField_BW, 'Enable', 'off');
            end
            ENCheckBox_AstopEnValueChanged(app, event);
            SynthesisButtonPushed(app, event);
        end

        % Drop down opening function: ButterworthDropDownFilterType1
        function ButterworthDropDownFilterType1Opening(app, event)
            
        end

        % Value changed function: ButterworthDropDownFilterType2
        function ButterworthDropDownFilterType2ValueChanged(app, event)
            fType = app.ButterworthDropDownFilterType2.Value;
            ENCheckBox_AstopEnValueChanged(app, event);
            SynthesisButtonPushed(app, event);
        end

        % Value changed function: ExactCheckBox
        function ExactCheckBoxValueChanged(app, event)
            value = app.ExactCheckBox.Value;
            if value
                set(app.LDropDown, 'Enable', 'off');
                set(app.CDropDown, 'Enable', 'off');
            else
                set(app.LDropDown, 'Enable', 'on');
                set(app.CDropDown, 'Enable', 'on');
            end
            SynthesisButtonPushed(app, event);
        end

        % Value changed function: CDropDown
        function CDropDownValueChanged(app, event)
            value = app.CDropDown.Value;
%             SynthesisButtonPushed(app, event);
        end

        % Value changed function: LDropDown
        function LDropDownValueChanged(app, event)
            value = app.LDropDown.Value;
%             SynthesisButtonPushed(app, event);
        end

        % Button down function: TabMag
        function TabMagButtonDown(app, event)
            set(app.logscaleCheckBox, 'Enable', 'on');
        end

        % Button down function: TabPhase
        function TabPhaseButtonDown(app, event)
            set(app.logscaleCheckBox, 'Enable', 'off');
        end

        % Button down function: PZTab
        function PZTabButtonDown(app, event)
            set(app.logscaleCheckBox, 'Enable', 'off');
        end

        % Button down function: TabTran
        function TabTranButtonDown(app, event)
            set(app.logscaleCheckBox, 'Enable', 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimTab = uitab(app.TabGroup);
            app.SimTab.Title = 'Sim';
            app.UIAxesSch = uiaxes(app.SimTab);
            xlabel(app.UIAxesSch, 'X')
            ylabel(app.UIAxesSch, 'Y')
            zlabel(app.UIAxesSch, 'Z')
            app.TabGroupPlot = uitabgroup(app.SimTab);
            app.TabMag = uitab(app.TabGroupPlot);
            app.TabMag.Title = 'Mag';
            app.TabMag.ButtonDownFcn = createCallbackFcn(app, @TabMagButtonDown, true);
            app.UIAxesMag = uiaxes(app.TabMag);
            title(app.UIAxesMag, 'Title')
            xlabel(app.UIAxesMag, 'X')
            ylabel(app.UIAxesMag, 'Y')
            zlabel(app.UIAxesMag, 'Z')
            app.logscaleCheckBox = uicheckbox(app.TabMag);
            app.logscaleCheckBox.Text = 'log scale';
            app.TabPhase = uitab(app.TabGroupPlot);
            app.TabPhase.Title = 'Phase';
            app.TabPhase.ButtonDownFcn = createCallbackFcn(app, @TabPhaseButtonDown, true);
            app.UIAxesPhase = uiaxes(app.TabPhase);
            title(app.UIAxesPhase, 'Title')
            xlabel(app.UIAxesPhase, 'X')
            ylabel(app.UIAxesPhase, 'Y')
            zlabel(app.UIAxesPhase, 'Z')
            app.PZTab = uitab(app.TabGroupPlot);
            app.PZTab.Title = 'PZ';
            app.PZTab.ButtonDownFcn = createCallbackFcn(app, @PZTabButtonDown, true);
            app.UIAxesPZ = uiaxes(app.PZTab);
            title(app.UIAxesPZ, 'Title')
            xlabel(app.UIAxesPZ, 'X')
            ylabel(app.UIAxesPZ, 'Y')
            zlabel(app.UIAxesPZ, 'Z')
            app.UIAxesPZ3D = uiaxes(app.PZTab);
            title(app.UIAxesPZ3D, 'Title')
            xlabel(app.UIAxesPZ3D, 'X')
            ylabel(app.UIAxesPZ3D, 'Y')
            zlabel(app.UIAxesPZ3D, 'Z')
            app.TabTran = uitab(app.TabGroupPlot);
            app.TabTran.Title = 'Tran';
            app.TabTran.ButtonDownFcn = createCallbackFcn(app, @TabTranButtonDown, true);
            app.UIAxesTran = uiaxes(app.TabTran);
            title(app.UIAxesTran, 'Title')
            xlabel(app.UIAxesTran, 'X')
            ylabel(app.UIAxesTran, 'Y')
            zlabel(app.UIAxesTran, 'Z')
            app.FilterParametersPanel = uipanel(app.SimTab);
            app.FilterParametersPanel.Title = 'FilterParameters';
            app.ShapeLabel = uilabel(app.FilterParametersPanel);
            app.ShapeLabel.Text = 'Shape';
            app.ButterworthDropDownFilterType1 = uidropdown(app.FilterParametersPanel);
            app.ButterworthDropDownFilterType1.Items = {'LPF', 'HPF', 'BPF', 'BRF'};
            app.ButterworthDropDownFilterType1.DropDownOpeningFcn = createCallbackFcn(app, @ButterworthDropDownFilterType1Opening, true);
            app.ButterworthDropDownFilterType1.ValueChangedFcn = createCallbackFcn(app, @ButterworthDropDownFilterType1ValueChanged, true);
            app.ButterworthDropDownFilterType1.Value = 'LPF';
            app.TypeLabel = uilabel(app.FilterParametersPanel);
            app.TypeLabel.Text = 'Type';
            app.ButterworthDropDownFilterType2 = uidropdown(app.FilterParametersPanel);
            app.ButterworthDropDownFilterType2.Items = {'Butterworth', 'Chebyshev I', 'Chebyshev II', 'Elliptic', 'Bessel', 'Gaussian', 'Legendre', 'LinearAmp'};
            app.ButterworthDropDownFilterType2.ValueChangedFcn = createCallbackFcn(app, @ButterworthDropDownFilterType2ValueChanged, true);
            app.ButterworthDropDownFilterType2.Value = 'Butterworth';
            app.OrderLabel = uilabel(app.FilterParametersPanel);
            app.OrderLabel.Text = 'Order';
            app.ButterworthDropDownOrder = uidropdown(app.FilterParametersPanel);
            app.ButterworthDropDownOrder.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.ButterworthDropDownOrder.Value = '5';
            app.FpLabel = uilabel(app.FilterParametersPanel);
            app.FpLabel.Text = 'Fp';
            app.EditField_F1 = uieditfield(app.FilterParametersPanel, 'text');
            app.EditField_F1.Value = '1G';
            app.FsLabel = uilabel(app.FilterParametersPanel);
            app.FsLabel.Text = 'Fs';
            app.EditField_F2 = uieditfield(app.FilterParametersPanel, 'text');
            app.EditField_F2.Value = '2G';
            app.AprLabel = uilabel(app.FilterParametersPanel);
            app.AprLabel.Text = 'Apr';
            app.EditField_Apr = uieditfield(app.FilterParametersPanel, 'text');
            app.EditField_Apr.Value = '0.04';
            app.AsrLabel = uilabel(app.FilterParametersPanel);
            app.AsrLabel.Text = 'Asr';
            app.EditField_Asr = uieditfield(app.FilterParametersPanel, 'text');
            app.RsLabel = uilabel(app.FilterParametersPanel);
            app.RsLabel.Text = 'Rs';
            app.EditField_Zin = uieditfield(app.FilterParametersPanel, 'text');
            app.EditField_Zin.Value = '50';
            app.RlLabel = uilabel(app.FilterParametersPanel);
            app.RlLabel.Text = 'Rl';
            app.EditField_Zout = uieditfield(app.FilterParametersPanel, 'text');
            app.EditField_Zout.Value = '50';
            app.BWLabel = uilabel(app.FilterParametersPanel);
            app.BWLabel.Text = 'BW';
            app.EditField_BW = uieditfield(app.FilterParametersPanel, 'text');
            app.EditField_BW.Value = '500M';
            app.HzLabel_Fp = uilabel(app.FilterParametersPanel);
            app.HzLabel_Fp.Text = 'Hz';
            app.HzLabel_BW = uilabel(app.FilterParametersPanel);
            app.HzLabel_BW.Text = 'Hz';
            app.dBLabel_Apr = uilabel(app.FilterParametersPanel);
            app.dBLabel_Apr.Text = 'dB';
            app.HzLabel_Fs = uilabel(app.FilterParametersPanel);
            app.HzLabel_Fs.Text = 'Hz';
            app.dBLabel_Asr = uilabel(app.FilterParametersPanel);
            app.dBLabel_Asr.Text = 'dB';
            app.Label = uilabel(app.FilterParametersPanel);
            app.Label.Text = 'Ω';
            app.Label_Rl = uilabel(app.FilterParametersPanel);
            app.Label_Rl.Text = 'Ω';
            app.ENCheckBox_AstopEn = uicheckbox(app.FilterParametersPanel);
            app.ENCheckBox_AstopEn.ValueChangedFcn = createCallbackFcn(app, @ENCheckBox_AstopEnValueChanged, true);
            app.ENCheckBox_AstopEn.Text = 'EN';
            app.dBLabel_Ap = uilabel(app.FilterParametersPanel);
            app.dBLabel_Ap.Text = 'dB';
            app.ApLabel_2 = uilabel(app.FilterParametersPanel);
            app.ApLabel_2.Text = 'Ap';
            app.EditField_Apass = uieditfield(app.FilterParametersPanel, 'text');
            app.EditField_Apass.Value = '3';
            app.SynthesisButton = uibutton(app.SimTab, 'push');
            app.SynthesisButton.ButtonPushedFcn = createCallbackFcn(app, @SynthesisButtonPushed, true);
            app.SynthesisButton.Text = 'Synthesis';
            app.ACPanel = uipanel(app.SimTab);
            app.ACPanel.Title = 'AC';
            app.fstartLabel = uilabel(app.ACPanel);
            app.fstartLabel.Text = 'fstart';
            app.EditField_fstart = uieditfield(app.ACPanel, 'text');
            app.EditField_fstart.Value = '1M';
            app.fstopLabel = uilabel(app.ACPanel);
            app.fstopLabel.Text = 'fstop';
            app.EditField_fstop = uieditfield(app.ACPanel, 'text');
            app.EditField_fstop.Value = '10G';
            app.ENCheckBox_ACEn = uicheckbox(app.ACPanel);
            app.ENCheckBox_ACEn.Text = 'AC';
            app.HzLabel_ACFstart = uilabel(app.ACPanel);
            app.HzLabel_ACFstart.Text = 'Hz';
            app.HzLabel_ACFstop = uilabel(app.ACPanel);
            app.HzLabel_ACFstop.Text = 'Hz';
            app.ENCheckBox_PZ = uicheckbox(app.ACPanel);
            app.ENCheckBox_PZ.Text = 'PZ';
            app.TranPanel = uipanel(app.SimTab);
            app.TranPanel.Title = 'Tran';
            app.tstopLabel = uilabel(app.TranPanel);
            app.tstopLabel.Text = 'tstop';
            app.EditField_tstop = uieditfield(app.TranPanel, 'text');
            app.EditField_tstop.Value = '25n';
            app.fsLabel = uilabel(app.TranPanel);
            app.fsLabel.Text = 'fs';
            app.EditField_fs = uieditfield(app.TranPanel, 'text');
            app.EditField_fs.Value = '1G';
            app.ENCheckBox_TranEn = uicheckbox(app.TranPanel);
            app.ENCheckBox_TranEn.Text = 'TRAN';
            app.HzLabel_TFstart = uilabel(app.TranPanel);
            app.HzLabel_TFstart.Text = 'Hz';
            app.HzLabel_TFstop = uilabel(app.TranPanel);
            app.HzLabel_TFstop.Text = 's';
            app.SimulationButton = uibutton(app.SimTab, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.ENCheckBox_TeeEn = uicheckbox(app.SimTab);
            app.ENCheckBox_TeeEn.ValueChangedFcn = createCallbackFcn(app, @ENCheckBox_TeeEnValueChanged, true);
            app.ENCheckBox_TeeEn.Text = 'Tee';
            app.ComponentValuePanel = uipanel(app.SimTab);
            app.ComponentValuePanel.Title = 'ComponentValue';
            app.ExactCheckBox = uicheckbox(app.ComponentValuePanel);
            app.ExactCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExactCheckBoxValueChanged, true);
            app.ExactCheckBox.Text = 'Exact';
            app.CDropDownLabel = uilabel(app.ComponentValuePanel);
            app.CDropDownLabel.Text = 'C';
            app.CDropDown = uidropdown(app.ComponentValuePanel);
            app.CDropDown.Items = {'E6', 'E12', 'E24', 'E48', 'E96', 'E192', 'User'};
            app.CDropDown.ValueChangedFcn = createCallbackFcn(app, @CDropDownValueChanged, true);
            app.CDropDown.Value = 'E24';
            app.LDropDownLabel = uilabel(app.ComponentValuePanel);
            app.LDropDownLabel.Text = 'L';
            app.LDropDown = uidropdown(app.ComponentValuePanel);
            app.LDropDown.Items = {'E6', 'E12', 'E24', 'E48', 'E96', 'E192', 'User'};
            app.LDropDown.ValueChangedFcn = createCallbackFcn(app, @LDropDownValueChanged, true);
            app.LDropDown.Value = 'E24';
            app.GuideTab = uitab(app.TabGroup);
            app.GuideTab.Title = 'Guide';
            app.OptTab = uitab(app.TabGroup);
            app.OptTab.Title = 'Opt';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eToolsFilterDesignApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end